classdef SatelliteTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Tracker3D                   matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        GroundTrackerPanel          matlab.ui.container.Panel
        UIAxes_2                    matlab.ui.control.UIAxes
        GNSSListBox                 matlab.ui.control.ListBox
        GNSSLabel                   matlab.ui.control.Label
        SkyTrackerPanel             matlab.ui.container.Panel
        MaskingdegEditField         matlab.ui.control.NumericEditField
        MaskingElevationLabel       matlab.ui.control.Label
        GroundStationPanel          matlab.ui.container.Panel
        AltitudekmEditField         matlab.ui.control.NumericEditField
        AltitudekmEditFieldLabel    matlab.ui.control.Label
        LatitudedegEditField        matlab.ui.control.NumericEditField
        LatitudedegEditFieldLabel   matlab.ui.control.Label
        LongitudedegEditField       matlab.ui.control.NumericEditField
        LongitudedegEditFieldLabel  matlab.ui.control.Label
        EPOCHhEditField             matlab.ui.control.NumericEditField
        EPOCHhLabel                 matlab.ui.control.Label
        TimeLineSlider              matlab.ui.control.Slider
        GNNSLoadButton              matlab.ui.control.Button
    end

    properties (Access = private)
        sky;                        % Skyplot
        fvar;                       % GNSS-file upload variable
        item;                       % GNSS-file item(type)
    end

    methods (Access = private)
        function skyplot_udf(app)
            field1 = fieldnames(app.fvar);
            str = sprintf('app.fvar.'+string(field1));
            field2 = fieldnames(eval(str));
            str = sprintf(str + '.' + string(field2(app.item)));

            m0 = eval(sprintf(str+'.M0'));
            e = eval(sprintf(str+'.e'));
            toc = eval(sprintf(str+'.toc'));
            OMG = eval(sprintf(str+'.OMEGA'));
            a = eval(sprintf(str+'.a'))/1000;               % in km
            i = eval(sprintf(str+'.i'));
            w = eval(sprintf(str+'.omega'));

            g_long = app.LongitudedegEditField.Value;
            g_lat = app.LatitudedegEditField.Value;
            g_h = app.AltitudekmEditField.Value;

            el_mask = app.MaskingdegEditField.Value;

            t0 = juliandate(datetime(toc))*24*60;           % in min
            dt = 10;                                        % in min, time-step
            timerange = app.TimeLineSlider.Value;           % in hour

            tocb = toc;
            tocb(4:6) = [0 0 0];
            bt = juliandate(datetime(tocb))*24*60 - t0;     % back-ward time
            
            r0 = 6371;                                      % in km, set mean radius
            mu = 3.986004418e5;                             % mu = GM, km^3/s^2, gravitational parameter
            n = sqrt(mu/a^3)*60;                            % average angular velocity in rad/min

            app.EPOCHhLabel.Text = sprintf('%d-%d-%d(+h):', tocb(1), tocb(2), tocb(3));

            cnt = 0;

            for j=0:dt:timerange*60
                cnt = cnt + 1;
                t = t0 + bt + j;
                m = m0 + n*(bt+j);

                toc_scaled = t/60/24;
                toc_scaled = datetime(toc_scaled,'convertfrom','juliandate');

                [y mt d] = ymd(toc_scaled);
                [h mn s] = hms(toc_scaled);
                toc_scaled = [y mt d h mn s];

                % UTC0 = pi/180*siderealTime(juliandate(datetime(toc_scaled)));
                PQW2ECI_CT = PQW2ECI(w*180/pi, i*180/pi, OMG*180/pi);
                ECI2ECEF_CT = ECI2ECEF_DCM(toc_scaled);
                ECEF2ENU_CT = ECEF2ENU_DCM(g_long, g_lat);

                E1 = m;
                E2 = m + e*sin(m);

                while 1
                    if abs(E2-E1) < 0.000001
                        break;
                    end

                    E1 = E2;
                    E2 = m + e*sin(E1);
                end

                E = E2;
                cv = (cos(E)-e)/(1-e*cos(E));
                sv = sqrt(1-e^2)*sin(E)/(1-e*cos(E));

                v = atan2(sv, cv);
                PQW_pos = solveRangeInPerifocalFrame(a, e, v*180/pi);
                % PQW_vel  = solveVelocityInPerifocalFrame(a, e, v*180/pi);
                
                ECI_pos = PQW2ECI_CT*PQW_pos;
                ECEF_pos = ECI2ECEF_CT*PQW2ECI_CT*PQW_pos;
                
                ECI_3d_x(cnt) = ECI_pos(1);
                ECI_3d_y(cnt) = ECI_pos(2);
                ECI_3d_z(cnt) = ECI_pos(3);

                long(cnt) = atan2(ECEF_pos(2), ECEF_pos(1))*180/pi;
                lat(cnt) = atan2(ECEF_pos(3), sqrt(ECEF_pos(1)^2+ECEF_pos(2)^2))*180/pi;
    
                ENU_pos = ECEF2ENU_CT*ECEF_pos;

                Az(cnt) = azimuth(ENU_pos');
                El(cnt) = elevation(ENU_pos'-[0 0 r0]-g_h*[0 0 1], el_mask);
            end

            ECEF_Axis_x = 10000*ECI2ECEF_CT'*[1 0 0]';
            ECEF_Axis_y = 10000*ECI2ECEF_CT'*[0 1 0]';
            ECEF_Axis_z = 10000*ECI2ECEF_CT'*[0 0 1]';

            hold(app.UIAxes, 'off');
            plot3(app.UIAxes, ECI_3d_x, ECI_3d_y, ECI_3d_z);                % 3D Tracker
            hold(app.UIAxes, 'on');
            plot3(app.UIAxes, 0, 0, 0, '.', 'MarkerSize', 40, 'MarkerFaceColor', 'b', 'MarkerEdgeColor', 'b');
            plot3(app.UIAxes, ECI_3d_x(end), ECI_3d_y(end), ECI_3d_z(end), '.', 'MarkerSize', 11, 'MarkerFaceColor', 'auto', 'MarkerEdgeColor', 'auto');
            plot3(app.UIAxes, [0 ECEF_Axis_x(1)], [0 ECEF_Axis_x(2)], [0 ECEF_Axis_x(3)], 'r');
            plot3(app.UIAxes, [0 ECEF_Axis_y(1)], [0 ECEF_Axis_y(2)], [0 ECEF_Axis_y(3)], 'r');
            plot3(app.UIAxes, [0 ECEF_Axis_z(1)], [0 ECEF_Axis_z(2)], [0 ECEF_Axis_z(3)], 'r');
            plot3(app.UIAxes, [0 10000], [0 0], [0 0], 'k');
            plot3(app.UIAxes, [0 0], [0 10000], [0 0], 'k');
            plot3(app.UIAxes, [0 0], [0 0], [0 10000], 'k');

            skyplot(app.sky, Az, El, 'MarkerSizeData', 2);                  % Skyplot Tracker

            hold(app.UIAxes_2, 'off');
            worldmap = imread('World_map2.bmp');
            image(app.UIAxes_2, worldmap, 'XData', [-180 180], 'YData', [90 -90]);
            set(app.UIAxes_2,'YDir', 'normal');
            grid(app.UIAxes_2,'on');
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, long, lat, '.', 'MarkerSize', 6);            % Ground Tracker
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sky = app.SkyTrackerPanel;
            app.sky.Units = 'pixels';
        end

        % Button pushed function: GNNSLoadButton
        function GNNSLoadButtonPushed(app, event)
            [fn,path] = uigetfile('*.mat');
            cd(path);

            app.fvar = load(fn,'-mat');
            field1 = fieldnames(app.fvar);
            str = sprintf('app.fvar.'+string(field1));
            field2 = fieldnames(eval(str));
            app.GNSSListBox.Items = field2;         % GNSS Indexing
        end

        % Value changed function: LongitudedegEditField
        function LongitudedegEditFieldValueChanged(app, event)
            skyplot_udf(app);
        end

        % Value changed function: AltitudekmEditField
        function AltitudekmEditFieldValueChanged(app, event)
            skyplot_udf(app);
        end

        % Value changed function: LatitudedegEditField
        function LatitudedegEditFieldValueChanged(app, event)
            skyplot_udf(app);
        end

        % Value changed function: MaskingdegEditField
        function MaskingdegEditFieldValueChanged(app, event)
            skyplot_udf(app);
        end

        % Value changed function: TimeLineSlider
        function TimeLineSliderValueChanged(app, event)
            value = app.TimeLineSlider.Value;
            app.EPOCHhEditField.Value = value;
            skyplot_udf(app);
        end

        % Value changed function: EPOCHhEditField
        function EPOCHhEditFieldValueChanged(app, event)
            value = app.EPOCHhEditField.Value;
            app.TimeLineSlider.Value = value;
            skyplot_udf(app);
        end

        % Clicked callback: GNSSListBox
        function GNSSListBoxClicked(app, event)
            app.item = event.InteractionInformation.Item;
            skyplot_udf(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GNNSLoadButton = uibutton(app.UIFigure, 'push');
            app.GNNSLoadButton.ButtonPushedFcn = createCallbackFcn(app, @GNNSLoadButtonPushed, true);
            app.GNNSLoadButton.Text = 'GNNS Load';
            app.TimeLineSlider = uislider(app.UIFigure);
            app.TimeLineSlider.Limits = [0 48];
            app.TimeLineSlider.MajorTicks = [0 6 12 18 24 30 36 42 48];
            app.TimeLineSlider.ValueChangedFcn = createCallbackFcn(app, @TimeLineSliderValueChanged, true);
            app.TimeLineSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48];
            app.EPOCHhLabel = uilabel(app.UIFigure);
            app.EPOCHhLabel.Text = 'EPOCH(+h):';
            app.EPOCHhEditField = uieditfield(app.UIFigure, 'numeric');
            app.EPOCHhEditField.ValueChangedFcn = createCallbackFcn(app, @EPOCHhEditFieldValueChanged, true);
            app.GroundStationPanel = uipanel(app.UIFigure);
            app.GroundStationPanel.Title = 'Ground Station';
            app.LongitudedegEditFieldLabel = uilabel(app.GroundStationPanel);
            app.LongitudedegEditFieldLabel.Text = 'Longitude(deg)';
            app.LongitudedegEditField = uieditfield(app.GroundStationPanel, 'numeric');
            app.LongitudedegEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudedegEditFieldValueChanged, true);
            app.LatitudedegEditFieldLabel = uilabel(app.GroundStationPanel);
            app.LatitudedegEditFieldLabel.Text = 'Latitude(deg)';
            app.LatitudedegEditField = uieditfield(app.GroundStationPanel, 'numeric');
            app.LatitudedegEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudedegEditFieldValueChanged, true);
            app.AltitudekmEditFieldLabel = uilabel(app.GroundStationPanel);
            app.AltitudekmEditFieldLabel.Text = 'Altitude(km)';
            app.AltitudekmEditField = uieditfield(app.GroundStationPanel, 'numeric');
            app.AltitudekmEditField.ValueChangedFcn = createCallbackFcn(app, @AltitudekmEditFieldValueChanged, true);
            app.SkyTrackerPanel = uipanel(app.UIFigure);
            app.SkyTrackerPanel.Title = 'Sky Tracker';
            app.MaskingElevationLabel = uilabel(app.SkyTrackerPanel);
            app.MaskingElevationLabel.Text = 'Masking(deg)';
            app.MaskingdegEditField = uieditfield(app.SkyTrackerPanel, 'numeric');
            app.MaskingdegEditField.ValueChangedFcn = createCallbackFcn(app, @MaskingdegEditFieldValueChanged, true);
            app.GNSSLabel = uilabel(app.UIFigure);
            app.GNSSLabel.Text = 'GNSS:';
            app.GNSSListBox = uilistbox(app.UIFigure);
            app.GNSSListBox.Items = {};
            app.GNSSListBox.ClickedFcn = createCallbackFcn(app, @GNSSListBoxClicked, true);
            app.GNSSListBox.Value = {};
            app.GroundTrackerPanel = uipanel(app.UIFigure);
            app.GroundTrackerPanel.Title = 'Ground Tracker';
            app.UIAxes_2 = uiaxes(app.GroundTrackerPanel);
            xlabel(app.UIAxes_2, 'Longitude')
            ylabel(app.UIAxes_2, 'Latitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-180 180];
            app.UIAxes_2.YLim = [-90 90];
            app.UIAxes_2.XTick = [-180 -90 0 90 180];
            app.UIAxes_2.XTickLabel = {'180\circW'; '90\circW'; '0\circ'; '90\circE'; '180\circE'};
            app.UIAxes_2.YTick = [-90 -60 -30 0 30 60 90];
            app.UIAxes_2.YTickLabel = {'90\circS'; '60\circS'; '30\circS'; '0\circ'; '30\circN'; '60\circN'; '90\circN'};
            app.Tracker3D = uipanel(app.UIFigure);
            app.Tracker3D.Title = '3D Tracker';
            app.UIAxes = uiaxes(app.Tracker3D);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatelliteTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end